classdef SWDapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        StwrzrankingButton           matlab.ui.control.Button
        WczytajdanezplikuButton_2    matlab.ui.control.Button
        wybierzmetodeDropDown        matlab.ui.control.DropDown
        wybierzmetodeDropDownLabel   matlab.ui.control.Label
        RankingLabel                 matlab.ui.control.Label
        KlasyLabel                   matlab.ui.control.Label
        AlternatywyzkryteriamiLabel  matlab.ui.control.Label
        UITable3                     matlab.ui.control.Table
        UITable2                     matlab.ui.control.Table
        UITable                      matlab.ui.control.Table
    end

    
    properties (Access = public)
        daneA = []; % Description
        daneK = [];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: WczytajdanezplikuButton_2
        function WczytajdanezplikuButton_2Pushed(app, event)
            dane1 = readtable('dane.xls','Sheet', 1);
            dane2 = readtable('dane.xls','Sheet', 2);
            app.UITable.Data = table2cell(dane1);
            app.UITable2.Data = table2cell(dane2);
            app.daneA = dane1{1:end, 3:end};
            app.daneK = dane2{1:end, 2:end};
        end

        % Button pushed function: StwrzrankingButton
        function StwrzrankingButtonPushed(app, event)
            
            wektorWag = [0.1, 0.1, 0.8]; 
            [liczbaAlternatyw,~] = size(app.daneA);
            [~,iloscKryteriow] = size(app.daneA);
            iloscKryteriow=iloscKryteriow-2;
            
            % okreslenie ilosci klas 
            % kod dostosowany do dwowch klas
            [liczbaKlas,~] = size(app.daneK);
            [~,iloscWymiarow] = size(app.daneK);
            iloscWymiarow = iloscWymiarow -1;
            
            % sprawdzenie punktow alternatyw
            alternatywyOK = zeros(liczbaAlternatyw, 1);
            for i =1:liczbaAlternatyw
                for j =3:iloscKryteriow+2
                    if app.daneA(i,j) >= app.daneK(1,j-1) && app.daneA(i,j) <= app.daneK(2,j-1)
                        alternatywyOK(i,1) = i;
                    else alternatywyOK(i,1) = 0;
                    end
                end 
            end
            
            liczbaAlternatywTemp = 0;
            for i =1:liczbaAlternatyw
                if alternatywyOK(i,1) == 0
                else liczbaAlternatywTemp = liczbaAlternatywTemp + 1;
                end
            end
            
            % uzupelnienie macierzy decyzyjnej
            id = 1;
            macierz_decyzyjna = zeros(liczbaAlternatywTemp,iloscKryteriow);
            for i =1:liczbaAlternatyw
                if alternatywyOK(i,1) ~= 0 
                    for j =3:iloscKryteriow+2
                        macierz_decyzyjna(id,j-2) = app.daneA(i,j);
                    end
                    id= id+1;
                end
            end
            liczbaAlternatyw = liczbaAlternatywTemp;
            
            % proces skalowania
            macierz_skalowana = zeros(liczbaAlternatyw,iloscKryteriow);
            
            for i =1:liczbaAlternatyw
                for j =1:iloscKryteriow
                    macierz_skalowana(i,j) = (macierz_decyzyjna(i,j) * wektorWag(j))/sqrt(sum((double(macierz_decyzyjna(:,j)).^2)));
                end 
            end
            
            % wyznaczenie wektora idealnego oraz antyidealnego
            % w rozwiazywanym przykladzie pierwsze kryterium jest jakosciowe, pozostale
            % dwa sa kosztowe
            wektorIdealny = [max(macierz_skalowana(:,1)) min(macierz_skalowana(:,2)) min(macierz_skalowana(:,3))];
            wektorAntyIdealny = [min(macierz_skalowana(:,1)) max(macierz_skalowana(:,2)) max(macierz_skalowana(:,3))];
            
            % wyznaczenie odleglosci w przestrzeni euklidesowej
            odleglosci = zeros(liczbaAlternatyw,2);
            for i =1:liczbaAlternatyw
                sumaIdealny = 0;
                sumaAntyIdealny = 0;
                for j =1:iloscKryteriow-2
                    sumaIdealny = sumaIdealny+(macierz_skalowana(i,j) - wektorIdealny(j)).^2;
                    sumaAntyIdealny = sumaAntyIdealny+(macierz_skalowana(i,j) - wektorAntyIdealny(j)).^2;
                end
             
                odleglosci(i,1) = sqrt(sumaIdealny);
                odleglosci(i,2) = sqrt(sumaAntyIdealny);
            end
            
            % uszeregowanie obiektow
            ranking = zeros(liczbaAlternatyw,2);
            for i =1:liczbaAlternatyw
                ranking(i,1) = i; 
                ranking(i,2) = odleglosci(i,2)/(odleglosci(i,1)+odleglosci(i,2));
            end
            
            % listing wynikow
            alternatywyOK
            macierz_decyzyjna
            wektorWag
            macierz_skalowana
            wektorIdealny
            wektorAntyIdealny
            odleglosci
            ranking
            app.UITable3.Data = ranking;
            %rysowanie
            figure(1);
            plot3(macierz_decyzyjna(:,1),macierz_decyzyjna(:,2),macierz_decyzyjna(:,3),'+');
            hold on;
            plot3(app.daneK(:,2),app.daneK(:,3),app.daneK(:,4),'o');
            hold off;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = '';
            app.UITable.RowName = {};
            app.UITable2 = uitable(app.UIFigure);
            app.UITable2.ColumnName = '';
            app.UITable2.RowName = {};
            app.UITable3 = uitable(app.UIFigure);
            app.UITable3.ColumnName = '';
            app.UITable3.RowName = {};
            app.AlternatywyzkryteriamiLabel = uilabel(app.UIFigure);
            app.AlternatywyzkryteriamiLabel.Text = 'Alternatywy z kryteriami';
            app.KlasyLabel = uilabel(app.UIFigure);
            app.KlasyLabel.Text = 'Klasy';
            app.RankingLabel = uilabel(app.UIFigure);
            app.RankingLabel.Text = 'Ranking';
            app.wybierzmetodeDropDownLabel = uilabel(app.UIFigure);
            app.wybierzmetodeDropDownLabel.Text = 'wybierz metode';
            app.wybierzmetodeDropDown = uidropdown(app.UIFigure);
            app.wybierzmetodeDropDown.Items = {'TOPSIS'};
            app.wybierzmetodeDropDown.Value = 'TOPSIS';
            app.WczytajdanezplikuButton_2 = uibutton(app.UIFigure, 'push');
            app.WczytajdanezplikuButton_2.ButtonPushedFcn = createCallbackFcn(app, @WczytajdanezplikuButton_2Pushed, true);
            app.WczytajdanezplikuButton_2.Text = 'Wczytaj dane z pliku';
            app.StwrzrankingButton = uibutton(app.UIFigure, 'push');
            app.StwrzrankingButton.ButtonPushedFcn = createCallbackFcn(app, @StwrzrankingButtonPushed, true);
            app.StwrzrankingButton.Text = 'Stwórz ranking';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SWDapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end