classdef SWDapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        WybrkryteriwButton           matlab.ui.control.Button
        WektorWagEditField           matlab.ui.control.EditField
        WektorWagEditFieldLabel      matlab.ui.control.Label
        StwrzrankingButton           matlab.ui.control.Button
        WczytajdanezplikuButton_2    matlab.ui.control.Button
        wybierzmetodeDropDown        matlab.ui.control.DropDown
        wybierzmetodeDropDownLabel   matlab.ui.control.Label
        RankingLabel                 matlab.ui.control.Label
        KlasyLabel                   matlab.ui.control.Label
        AlternatywyzkryteriamiLabel  matlab.ui.control.Label
        UITable3                     matlab.ui.control.Table
        UITable2                     matlab.ui.control.Table
        UITable                      matlab.ui.control.Table
    end

    
    properties (Access = public)
        daneA = []; % Description
        daneK = [];
        wektorWag = [0.8 0.1 0.1];
        alternatywy = [];
        dane1 = [];
        dane2 = [];
        options = [];
        selection = [1 2 3];
        pareto_idx = [];
        A0 = [];
        A1 = [];
        A2 = [];
        A3 = [];


    end
    
    methods (Access = public)
        %%%%%%%%%%%%%%%%%%%%%%%%%% RSM %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function [result, rank] = RSM(app, U, A0, A1, A2, A3, min_max)
            [P_idx, P] = app.pareto(U, min_max);
            decisions = size(P, 1);     % Number of decisions
            
            % Checking the integrity of reference points sets
            if ~app.check_integrity(A0, min_max)
                disp("No integrity in A0 set")
                app.UITable3.Data = "No integrity in A0 set";
                result = -404;
                return
            end 
            if ~app.check_integrity(A1, min_max)
                disp("No integrity in A1 set")
                app.UITable3.Data = "No integrity in A1 set";
                result = -404;
                return
            end
            if ~app.check_integrity(A2, min_max)
                disp("No integrity in A2 set")
                app.UITable3.Data = "No integrity in A2 set";
                result = -404;
                return
            end
            if ~app.check_integrity(A3, min_max)
                disp("No integrity in A3 set")
                app.UITable3.Data = "No integrity in A3 set";
                result = -404;
                return
            end 
        
            distance0 = zeros(decisions, size(A0, 1));
            distance1 = zeros(decisions, size(A1, 1));
            distance2 = zeros(decisions, size(A2, 1));
            distance3 = zeros(decisions, size(A3, 1));
            
            for i = 1:decisions
                for j = 1:size(A0, 1)
                    distance0(i, j) = norm(P(i, :) - A0(j, :));
                end
                for j = 1:size(A1, 1)
                    distance1(i, j) = norm(P(i, :) - A1(j, :));
                end
                for j = 1:size(A2, 1)
                    distance2(i, j) = norm(P(i, :) - A2(j, :));
                end
                for j = 1:size(A3, 1)
                    distance3(i, j) = norm(P(i, :) - A3(j, :));
                end
            end
               
            scoring_fcn = scoring_function(app, distance0, distance1, decisions);
            rank = sortrows([P, scoring_fcn], 4);
            result = rank(1, 1:size(rank, 2)-1);
            
            figure;
            plot3(A0(:, 1), A0(:, 2), A0(:, 3), "bo-");          % A0
            hold on;
            grid on;
            plot3(A1(:, 1), A1(:, 2), A1(:, 3), "go-");          % A1
            plot3(A2(:, 1), A2(:, 2), A2(:, 3), "co-");          % A2
            plot3(A3(:, 1), A3(:, 2), A3(:, 3), "ro-");          % A3
            plot3(U(:, 1), U(:, 2), U(:, 3), "yo");              % U 
            plot3(P(:, 1), P(:, 2), P(:, 3), "mo");             % Pareto 
            plot3(result(:,1), result(:, 2), result(:, 3), "k*")      % result
            xlabel("X");
            ylabel("Y");
            zlabel("Z");
            legend("A0", "A1", "A2", "A3", "U", "PU", "Best value")
        
        end
        
        function [pareto_idx, pareto_set] = pareto(app, U, min_max)
            % min_max is a array of booleans - true means min, false means max
            % Determining pareto set
            U_size = size(U, 1);
            pareto_set = [];
            pareto_idx = [];
            
            for i = 1:U_size
                is_dominated = false;
                for j = 1:U_size
                    if i ~= j
                        control_sum = 0;
                        for col = 1:size(U, 2);
                            if min_max(col)
                                if U(i, col) >= U(j, col)
                                    % Checking if point is dominated
                                    control_sum = control_sum + 1;
                                end
                            else
                                if U(i, col) <= U(j, col)
                                    % Checking if point is dominated
                                    control_sum = control_sum + 1;
                                end
                            end
                        end
        
                        if control_sum == size(U, 2)
                            is_dominated = true;
                            break;
                        end
                    end
                end
        
                if ~is_dominated
                    pareto_set = [pareto_set; U(i, :)];
                    app.pareto_idx = [app.pareto_idx; i];
                end
            end
        end
        
        function [integrity] = check_integrity(app, class, min_max)
            % The set that is integral must not contain dominated points 
            class_size = size(class, 1);
            integrity = true;
        
            for i = 1:class_size
                for j = 1:class_size
                    if i ~= j
                        control_sum = 0;
                        for col = 1:size(class, 2);
                            if min_max(col)
                                if class(i, col) >= class(j, col)
                                    % Checking if point is dominated
                                    control_sum = control_sum + 1;
                                end
                            else
                                if class(i, col) <= class(j, col)
                                    % Checking if point is dominated
                                    control_sum = control_sum + 1;
                                end
                            end
                        end
        
                        if control_sum == size(class, 2)
                            integrity = false;
                            return
                        end
                    end
                end
            end
        end
        
        function [scoring_result] = scoring_function(app, distance0, distance1, decisions)
            scoring_result = zeros(decisions, 1);
        
            for i = 1:decisions
                mean_A0 = mean(distance0(i, :));
                mean_A1 = mean(distance1(i, :));
                scoring_result(i, 1) = mean([mean_A0, mean_A1]);
            end
        end


        %%%%%%%%%%%%%%%%%%%%%%%%%%%%% SP-CS %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function out = SPCS(app, alternatives, aspirationPoints, statusQuoPoints, directions)
            % rysowanie wszystkich alteranatyw, punktow aspiracji oraz punktow
            % status quo
            figure; grid on;
            plot3(alternatives(:, 1), alternatives(:, 2), alternatives(:, 3), 'k.'); hold on
            plot3(aspirationPoints(:, 1), aspirationPoints(:, 2), aspirationPoints(:, 3), 'g*')
            plot3(statusQuoPoints(:, 1), statusQuoPoints(:, 2), statusQuoPoints(:, 3), 'r*')
            
            % usuwanie punktow zdominowanych na podstawie optymalizacji
            nondominatedAlternatives = app.removeDominatedAlternativevs(alternatives, directions);
        
            % rysowanie alternatyw niezdominowanch
            plot3(nondominatedAlternatives(:, 1), nondominatedAlternatives(:, 2), nondominatedAlternatives(:, 3), 'mo')
            
            % dodanie niewielkich zaklocen do punktow aspiracji i statusquo
            perturbedAspirationPoints = app.applyUncertainties(aspirationPoints);
            perturbedStatusQuoPoints = app.applyUncertainties(statusQuoPoints);
        
            % tworzenie krzywej szkieletowej, rysowanie jej oraz obliczanie
            % wartosci skoringowych
            scoringValues = app.countScoringValues(nondominatedAlternatives, perturbedAspirationPoints, perturbedStatusQuoPoints);
        
            % wybieranie punktu kompromisowego
            ranking = app.findCompromisePoint(nondominatedAlternatives, scoringValues);
            legend(["Alternatywy", "Punkty Aspiracji", "Punkty StatusQuo", "Alternatywy niezdominowane", "Krzywa lamana", "Najlepsza alternatywa"], Location="bestoutside")
            hold off;
        
            out = app.doRanking(alternatives, ranking);
        end
        
        
        % Funckja zwraca punkty niezdominowane
        function nondomiantedAlternatives = removeDominatedAlternativevs(app, alternatives, directions)
            alternativesSize = size(alternatives, 1);
            nondomiantedAlternatives = [];
            
            for i = 1:alternativesSize
                isDominated = false;
                for j = 1:alternativesSize
                    if i ~= j
                        controlSum = 0;
                        for col = 1: size(alternatives, 2)
                            if directions(col)
                                if alternatives(i, col) >= alternatives(j, col)
                                    controlSum = controlSum + 1;
                                end
                            else
                                if alternatives(i, col) <= alternatives(j, col)
                                controlSum = controlSum + 1;
                                end
                            end
                        end
        
                        if controlSum == size(alternatives, 2)
                            isDominated = true;
                            break;
                        end
                    end
                end
                if ~isDominated
                    nondomiantedAlternatives = [nondomiantedAlternatives; alternatives(i, :)];
                end
            end
        end
        
        
        % Funckja dodaje zakłóceia
        function perturbedPoints = applyUncertainties(app, points)
            perturbedPoints = points + points .* (0.01 * randn(size(points)));
        end
        
        
        % Funckja tworzy krzywa Woronoja oraz zwraca wartosci skoringowe dla kazdej
        % niezdominowanej alterantywy
        function scoringValues = countScoringValues(app, nondominatedAlternatives, aspirationPoints, statusQuoPoints)
            n = size(nondominatedAlternatives, 1);
            scoringValues = zeros(1, n);
            skeletonCurve = [];
        
            for i = 1:size(aspirationPoints, 1)
                aspirationPoint = aspirationPoints(i, :);
                statusQuoPoint = statusQuoPoints(i, :);
                interpolatedCurve = app.interpolateLinear(statusQuoPoint, aspirationPoint);
                skeletonCurve = [skeletonCurve; interpolatedCurve];
            end
            app.generatePlot(skeletonCurve)
        
            for i = 1:n
                minFUDistance = inf;
        
                for j = 1:size(aspirationPoints, 1)    
                    for k = 1:size(statusQuoPoints,1)
                        [currentFUDistance, currentTGamma] = app.findDistanceTGamma(nondominatedAlternatives(i, 1:3), aspirationPoints(j, 1:3), statusQuoPoints(k, 1:3));
                        
                        if (currentFUDistance < minFUDistance)
                            minFUDistance = currentFUDistance;
                            bestTGamma = currentTGamma;
                        end
                    end
                end
                scoringValues(i) = bestTGamma + minFUDistance; 
            end
        end
        
        
        % Funckja oblicza wartosc skoringowa
        function [fUDistance, TGamma] = findDistanceTGamma(app, nondominadedAlternative, aspirationPoint, statusQuoPoint)
            d = aspirationPoint - statusQuoPoint;
            d1 = nondominadedAlternative - statusQuoPoint;
            fUDistance = norm(d1) * sqrt(1 - (dot(d, d1) / (norm(d) * norm(d1))) ^ 2);
            x = norm(d1) * dot(d1, d) / (norm(d1) * norm(d)); 
            TGamma = (x / norm(d));
        end
        
        
        % Funckja znajduje punkt kompromisowy
        function ranking = findCompromisePoint(app, nondominatedAlternatives, scoringValues)
            [~, bestIndex] = min(scoringValues);
            bestAlternative = nondominatedAlternatives(bestIndex, :);
            
            [~, n] = size(nondominatedAlternatives); 
            nondominatedAlternatives(:, n + 1) = scoringValues;
            ranking = sortrows(nondominatedAlternatives, n + 1);
            ranking = ranking(:, 1:n);
        
            plot3(bestAlternative(1), bestAlternative(2), bestAlternative(3), "hexagram", 'Color', [0.6350 0.0780 0.1840])
        end
        
        
        % Funkcja rysuje krzywa Woronoja
        function generatePlot(app, skeletonCurves)
            numPoints = size(skeletonCurves, 1);
            x = zeros([1, numPoints]);
            y = zeros([1, numPoints]);
            z = zeros([1, numPoints]);
            
            for i = 1:numPoints
                x(i) = skeletonCurves(i, 1);
                y(i) = skeletonCurves(i, 2);
                z(i) = skeletonCurves(i, 3);
            end
            
            plot3(x, y, z, 'c-')
        end
        
        
        % Funkcja interpoluje pojedyncza lamana
        function interpolatedCurve = interpolateLinear(app, point1, point2)
            t = linspace(0, 1, 2);
            interpolatedCurve = point1 + t' * (point2 - point1);
        end
        
        
        % Funkcja tworzy ranking
        function out = doRanking(app, alternatives, ranking)
            out = zeros([1, size(ranking, 1)]);
        
            for i = 1:size(ranking, 1)
                for j = 1:size(alternatives, 1)
                    if ranking(i,:) == alternatives(j,:)
                        out(i) = j;
                        break
                    end
                end
            end
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: WczytajdanezplikuButton_2
        function WczytajdanezplikuButton_2Pushed(app, event)
            [fileName, filePath] = uigetfile({'*.xls', 'Pliki Excel (*.xls)'; '*.*', 'Wszystkie pliki (*.*)'}, 'Wybierz plik Excel');
            
            if isequal(fileName, 0)
                return;
            end
            fullFilePath = fullfile(filePath, fileName);
            figure(app.UIFigure);
            
            app.dane1 = readtable(fullFilePath,'Sheet', 1);
            app.dane2 = readtable(fullFilePath,'Sheet', 2);
            app.A0 = table2array(readtable(fullFilePath,'Sheet', 3));
            app.A1 = table2array(readtable(fullFilePath,'Sheet', 4));
            app.A2 = table2array(readtable(fullFilePath,'Sheet', 5));
            app.A3 = table2array(readtable(fullFilePath,'Sheet', 6));
            app.UITable.Data = app.dane1;
            originalColumnNames1 = app.dane1.Properties.VariableDescriptions;
            app.UITable.ColumnName = originalColumnNames1;

            app.UITable2.Data = app.dane2;
            originalColumnNames2 = app.dane2.Properties.VariableDescriptions;
            app.UITable2.ColumnName = originalColumnNames2;
            
            app.daneA = app.dane1{1:end, 3:end};
            app.daneK = app.dane2{1:end, 2:end};
            app.alternatywy = app.dane1{1:end, 2};
            figure(app.UIFigure)
        end

        % Button pushed function: StwrzrankingButton
        function StwrzrankingButtonPushed(app, event)
            value = app.wybierzmetodeDropDown.Value;
            if value == "TOPSIS"
                [liczbaAlternatyw,~] = size(app.daneA);
                iloscKryteriow = 3;
                
                [liczbaKlas,~] = size(app.daneK);
                [~,iloscWymiarow] = size(app.daneK);

                % sprawdzenie punktow alternatyw
                alternatywyOK = zeros(liczbaAlternatyw, 1);
                for i =1:liczbaAlternatyw
                    for j = 1:3
                        if app.daneA(i,app.selection(j)) >= app.daneK(1,app.selection(j)) && app.daneA(i,app.selection(j)) <= app.daneK(2,app.selection(j))
                            alternatywyOK(i,1) = i;
                        else alternatywyOK(i,1) = 0;
                        end
                    end 
                end
                
                liczbaAlternatywTemp = 0;
                for i =1:liczbaAlternatyw
                    if alternatywyOK(i,1) == 0
                    else liczbaAlternatywTemp = liczbaAlternatywTemp + 1;
                    end
                end
                
                % uzupelnienie macierzy decyzyjnej
                id = 1;
                macierz_decyzyjna = zeros(liczbaAlternatywTemp,iloscKryteriow);
                for i =1:liczbaAlternatyw
                    if alternatywyOK(i,1) ~= 0 
                        for j = 1:3
                            macierz_decyzyjna(id,j) = app.daneA(i,app.selection(j));
                        end
                        id= id+1;
                    end
                end
                liczbaAlternatyw = liczbaAlternatywTemp;
                
                % proces skalowania
                macierz_skalowana = zeros(liczbaAlternatyw,iloscKryteriow);

                for i =1:liczbaAlternatyw
                    for j = 1:3
                        macierz_skalowana(i,j) = (macierz_decyzyjna(i,j) * app.wektorWag(j))/sqrt(sum((double(macierz_decyzyjna(:,j)).^2)));
                    end 
                end
                
                % wyznaczenie wektora idealnego oraz antyidealnego
                %wektorIdealny = [max(macierz_skalowana(:,1)) min(macierz_skalowana(:,2)) min(macierz_skalowana(:,3))];
                %wektorAntyIdealny = [min(macierz_skalowana(:,1)) max(macierz_skalowana(:,2)) max(macierz_skalowana(:,3))];
                
                wektorIdealny = zeros(1,3);
                wektorAntyIdealny = zeros(1,3);

                for i = 1:3
                    if strcmp(app.options{i}, 'max')
                        wektorIdealny(i) = max(macierz_skalowana(:,i));
                        wektorAntyIdealny(i) = min(macierz_skalowana(:,i));
                    elseif strcmp(app.options{i}, 'min')
                        wektorIdealny(i) = min(macierz_skalowana(:,i));
                        wektorAntyIdealny(i) = max(macierz_skalowana(:,i));
                    end
                end

                % wyznaczenie odleglosci w przestrzeni euklidesowej
                odleglosci = zeros(liczbaAlternatyw,2);
                for i =1:liczbaAlternatyw
                    sumaIdealny = 0;
                    sumaAntyIdealny = 0;
                    for j = 1:3
                        sumaIdealny = sumaIdealny+(macierz_skalowana(i,j) - wektorIdealny(j)).^2;
                        sumaAntyIdealny = sumaAntyIdealny+(macierz_skalowana(i,j) - wektorAntyIdealny(j)).^2;
                    end
                 
                    odleglosci(i,1) = sqrt(sumaIdealny);
                    odleglosci(i,2) = sqrt(sumaAntyIdealny);
                end
                
                % uszeregowanie obiektow
                ranking = cell(liczbaAlternatyw,2);
                for i =1:liczbaAlternatyw
                    ranking{i,1} = app.alternatywy(i); 
                    ranking{i,2} = odleglosci(i,2)/(odleglosci(i,1)+odleglosci(i,2));
                end
                ranking_sorted = flip(sortrows(ranking, 2), 1);

                ranking_table = cell2table(ranking_sorted);
                app.UITable3.Data = ranking_table;
                %rysowanie
                figure(1);
                plot3(macierz_decyzyjna(:,1),macierz_decyzyjna(:,2),macierz_decyzyjna(:,3),'+');
                hold on;
                plot3(app.daneK(:,app.selection(1)),app.daneK(:,app.selection(2)),app.daneK(:,app.selection(3)),'o');
                originalColumnNames1 = app.dane1.Properties.VariableDescriptions;
                xlabel(originalColumnNames1(app.selection(1) + 2))
                ylabel(originalColumnNames1(app.selection(2) + 2))
                zlabel(originalColumnNames1(app.selection(3) + 2))
                hold off;
            end


            if value == "RSM"
                v_min_max = [0 0 0];
                for i = 1:3
                    if app.options(i) == "min"
                        v_min_max(i) = 1;
                    else
                        v_min_max(i) = 0;
                    end
                end


                [result, rank] = app.RSM(app.daneA(:, app.selection), app.A0(:, app.selection), app.A1(:, app.selection), app.A2(:, app.selection), app.A3(:, app.selection), v_min_max);
                
                [liczbaAlternatyw,~] = size(rank);
                ranking = cell(liczbaAlternatyw,2);
                for i =1:liczbaAlternatyw
                    ranking{i,1} = app.alternatywy(app.pareto_idx(i)); 
                    ranking{i,2} = rank(i,4);
                end
                ranking_table = cell2table(ranking);
                app.UITable3.Data = ranking_table;


            end


            if value == "SP-CS"
                v_min_max = [0 0 0];
                for i = 1:3
                    if app.options(i) == "min"
                        v_min_max(i) = 1;
                    else
                        v_min_max(i) = 0;
                    end
                end

                ranking_idx = app.SPCS(app.daneA(:, app.selection), app.A1(:, app.selection), app.A2(:, app.selection), v_min_max);
                ranking = cell(length(ranking_idx), 2);
                for i =1:length(ranking_idx)
                    ranking{i,1} = i; 
                    ranking{i,2} = app.alternatywy(ranking_idx(i));
                end
                ranking_table = cell2table(ranking);
                app.UITable3.Data = ranking_table;


            end


        end

        % Value changed function: wybierzmetodeDropDown
        function DropDownValueChanged(app, event)
            selectedOption = app.wybierzmetodeDropDown.Value;
            
            if strcmp(selectedOption, "TOPSIS")
                app.WektorWagEditField.Visible = 'on';
                app.WektorWagEditFieldLabel.Visible = 'on';
            else
                app.WektorWagEditField.Visible = 'off';
                app.WektorWagEditFieldLabel.Visible = 'off';
            end
        end

        % Value changed function: WektorWagEditField
        function EditFieldValueChanged(app, event)
            weights = str2num(app.WektorWagEditField.Value);
            if numel(weights) ~= 3
                warndlg('Wektor musi mieć długość równą 3 (wagi dla trzech kryteriów)', 'Ostrzeżenie');
            else
                app.wektorWag = weights;
            end
        end

        % Button pushed function: WybrkryteriwButton
        function WybrkryteriwButtonPushed(app, event)
            figure(app.UIFigure);
            columnNames = app.dane1.Properties.VariableNames;
            prompt = 'Wybierz trzy kryteria:';
            [app.selection, ok] = listdlg('ListString', columnNames(3:end), 'PromptString', prompt, 'SelectionMode', 'multiple', 'ListSize', [300, 150]);
            
            if ~ok
                return;
            end
            
            if numel(app.selection) ~= 3
                warndlg('Wybierz 3 kryteria', 'Ostrzeżenie');
            end
            

            app.options = cell(1, numel(app.selection));
            for i = 1:numel(app.selection)
                figure(app.UIFigure);
                prompt = sprintf('Wybierz opcję dla kryterium "%s":', columnNames{app.selection(i) + 2});
                result = questdlg(prompt, 'Wybór maksymalizowania/minimalizowania kryterium', 'min', 'max', 'Anuluj');
                if strcmp(result, 'Anuluj')
                    break
                else
                    app.options{i} = result;
                end
            end

            figure(app.UIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = '';
            app.UITable.RowName = {};
            app.UITable2 = uitable(app.UIFigure);
            app.UITable2.ColumnName = '';
            app.UITable2.RowName = {};
            app.UITable3 = uitable(app.UIFigure);
            app.UITable3.ColumnName = '';
            app.UITable3.RowName = {};
            app.AlternatywyzkryteriamiLabel = uilabel(app.UIFigure);
            app.AlternatywyzkryteriamiLabel.Text = 'Alternatywy z kryteriami';
            app.KlasyLabel = uilabel(app.UIFigure);
            app.KlasyLabel.Text = 'Klasy';
            app.RankingLabel = uilabel(app.UIFigure);
            app.RankingLabel.Text = 'Ranking';
            app.wybierzmetodeDropDownLabel = uilabel(app.UIFigure);
            app.wybierzmetodeDropDownLabel.Text = 'wybierz metode';
            app.wybierzmetodeDropDown = uidropdown(app.UIFigure);
            app.wybierzmetodeDropDown.Items = {'TOPSIS', 'RSM', 'SP-CS'};
            app.wybierzmetodeDropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.wybierzmetodeDropDown.Value = 'RSM';
            app.WczytajdanezplikuButton_2 = uibutton(app.UIFigure, 'push');
            app.WczytajdanezplikuButton_2.ButtonPushedFcn = createCallbackFcn(app, @WczytajdanezplikuButton_2Pushed, true);
            app.WczytajdanezplikuButton_2.Text = 'Wczytaj dane z pliku';
            app.StwrzrankingButton = uibutton(app.UIFigure, 'push');
            app.StwrzrankingButton.ButtonPushedFcn = createCallbackFcn(app, @StwrzrankingButtonPushed, true);
            app.StwrzrankingButton.Text = 'Stwórz ranking';
            app.WektorWagEditFieldLabel = uilabel(app.UIFigure);
            app.WektorWagEditFieldLabel.Visible = 'off';
            app.WektorWagEditFieldLabel.Text = 'Wektor Wag';
            app.WektorWagEditField = uieditfield(app.UIFigure, 'text');
            app.WektorWagEditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.WektorWagEditField.Visible = 'off';
            app.WektorWagEditField.Value = '[0.8 0.1 0.1]';
            app.WybrkryteriwButton = uibutton(app.UIFigure, 'push');
            app.WybrkryteriwButton.ButtonPushedFcn = createCallbackFcn(app, @WybrkryteriwButtonPushed, true);
            app.WybrkryteriwButton.Text = 'Wybór kryteriów';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SWDapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end